classdef Tugas4_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ClassificationLabel       matlab.ui.control.Label
        ClassificationTitleLabel  matlab.ui.control.Label
        ControlPanel              matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        StatusLabel               matlab.ui.control.Label
        LoadModelButton           matlab.ui.control.Button
        StatusLamp                matlab.ui.control.Lamp
        TrainModelButton          matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        ClassifyImageButton       matlab.ui.control.Button
        LoadImageButton           matlab.ui.control.Button
        UIAxesSegmented           matlab.ui.control.UIAxes
        UIAxesFill                matlab.ui.control.UIAxes
        UIAxesOriginal            matlab.ui.control.UIAxes
        UIAxesEdge                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage    % RGB or grayscale original image
        GrayImage        % Grayscale version of OriginalImage
        EdgeImage        % Binary or grayscale edge image
        MaskedImage      % Masked image for segmentation control
        SegmentedImage   % Segmentation result

        SavedModel       % Prediction Model
        ClassNames       
    end
    
    methods (Access = private)
        function E = edgeCanny(~, f)        
            E = edge(f, 'canny');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [fname, fpath] = uigetfile({'*.jpg;*.png;*.bmp','Image Files'}, ...
                                       'Select Input Image');
            if isequal(fname,0)
                return; % user cancelled
            end
            
            fullPath = fullfile(fpath, fname);
            img = imread(fullPath);
            
            app.OriginalImage = img;
            
            % Convert to grayscale if needed
            if size(img, 3) == 3
                app.GrayImage = rgb2gray(img);
            else
                app.GrayImage = img;
            end
            
            % Display original
            imshow(app.OriginalImage, 'Parent', app.UIAxesOriginal);
        end

        % Button pushed function: ClassifyImageButton
        function ClassifyImageButtonPushed(app, event)
            if isempty(app.GrayImage)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                return;
            end
            
            % Normalize image to double [0,1] just in case
            f = im2double(app.GrayImage);
            
            app.EdgeImage = app.edgeCanny(f);
            
            % Show edge image
            imshow(app.EdgeImage, 'Parent', app.UIAxesEdge);

            % Make sure we have edges
            if isempty(app.EdgeImage)
                uialert(app.UIFigure, 'Please detect edges first.', 'No Edge Image');
                return;
            end
        
            edgeImg = app.EdgeImage;
        
            % Close edges by radius strelC
            gap_size = 5;
           
            se = strel('disk', gap_size);
            % closedEdges = imdilate(edgeImg, se);
            closedEdges = imclose(edgeImg, se);

            % Fill up closed edges
            filled = imfill(closedEdges, 'holes');

            X = 100;

            % Keep objects with area >= X pixels
            mask = bwareaopen(filled, X);

            app.MaskedImage = mask;
            imshow(app.MaskedImage, 'Parent', app.UIAxesFill);
            title(app.UIAxesFill, 'Fill Image');
            % figure, imshow();
        
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Original image is not available.', 'Error');
                return;
            end
        
            rgb = app.OriginalImage;
            % Ensure we have an RGB image for display
            if size(rgb, 3) == 1
                rgb = cat(3, rgb, rgb, rgb);
            end
        
            segmented = rgb;
            % Set background (mask == 0) to black
            for c = 1:3
                temp = segmented(:, :, c);
                temp(~mask) = 0;
                segmented(:, :, c) = temp;
            end
        
            % Store and display the result
            app.SegmentedImage = segmented;
            imshow(app.SegmentedImage, 'Parent', app.UIAxesSegmented);
            title(app.UIAxesSegmented, 'Segmented Image');

            % Predictions
            if isempty(app.SavedModel)
                uialert(app.UIFigure, 'Train or Load a model first.', 'Error');
                return;
            end
            net = app.SavedModel;

            % Resize Image
            inputSize = net.Layers(1).InputSize(1:2);
            resizedImg = imresize(app.SegmentedImage, inputSize);

            % Convert the image to the required data type (single)
            imgPred = single(resizedImg);

            Yscores = predict(net, imgPred);
            [maxScores, idx] = max(Yscores, [], 2); 

            % Set the confidence threshold 
            threshold = 0.5; 
            otherLabel = categorical({'others'});

            Ypred = app.ClassNames(idx);

            % Apply rejection logic
            lowConfidenceIndices = maxScores < threshold;
            Ypred(lowConfidenceIndices) = otherLabel;

            % Output Prediction
            app.ClassificationLabel.Text = char(Ypred);
        end

        % Callback function
        function SegmentButtonPushed(app, event)
            % % Make sure we have edges
            % if isempty(app.EdgeImage)
            %     uialert(app.UIFigure, 'Please detect edges first.', 'No Edge Image');
            %     return;
            % end
            % 
            % edgeImg = app.EdgeImage;
            % 
            % % Close edges by radius strelC
            % se = strel('disk', 2);
            % % closedEdges = imdilate(edgeImg, se);
            % closedEdges = imclose(edgeImg, se);
            % 
            % % Fill up closed edges
            % filled = imfill(closedEdges, 'holes');
            % 
            % X = app.AreaOpenSlider.Value;
            % 
            % % Keep objects with area >= X pixels
            % mask = bwareaopen(filled, X);
            % 
            % app.MaskedImage = mask;
            % imshow(app.MaskedImage, 'Parent', app.UIAxesFill);
            % title(app.UIAxesFill, 'Fill Image');
            % % figure, imshow();
            % 
            % if isempty(app.OriginalImage)
            %     uialert(app.UIFigure, 'Original image is not available.', 'Error');
            %     return;
            % end
            % 
            % rgb = app.OriginalImage;
            % % Ensure we have an RGB image for display
            % if size(rgb, 3) == 1
            %     rgb = cat(3, rgb, rgb, rgb);
            % end
            % 
            % segmented = rgb;
            % % Set background (mask == 0) to black
            % for c = 1:3
            %     temp = segmented(:, :, c);
            %     temp(~mask) = 0;
            %     segmented(:, :, c) = temp;
            % end
            % 
            % % Store and display the result
            % app.SegmentedImage = segmented;
            % imshow(app.SegmentedImage, 'Parent', app.UIAxesSegmented);
            % title(app.UIAxesSegmented, 'Segmented Image');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.OriginalImage  = [];
            app.GrayImage      = [];
            app.EdgeImage      = [];
            app.MaskedImage    = [];
            app.SegmentedImage = [];
            
            cla(app.UIAxesOriginal, 'reset');
            cla(app.UIAxesEdges, 'reset');
            cla(app.UIAxesMasked, 'reset');
            cla(app.UIAxesSegmented, 'reset');
            
            title(app.UIAxesOriginal, 'Original Image');
            title(app.UIAxesEdges, 'Edge Image');
            title(app.UIAxesFill, 'Masked Image');
            title(app.UIAxesSegmented, 'Segmented Object');

            app.ClassificationLabel.Text = '...';
        end

        % Callback function
        function OperatorDropDownValueChanged(app, event)
            % app.cconstantEditField.Enable = "off";
            % app.sigmaEditField.Enable = "off";
            % app.AutoThresholdCheckBox.Enable = "off";
            % 
            % op = app.OperatorDropDown.Value;
            % 
            % % Choose a suggested default threshold depending on operator
            % switch op
            %     case 'Laplace'
            %         defaultT = 0.20;
            %     case 'LoG'
            %         defaultT = 0.20;
            %         app.sigmaEditField.Enable = "on";
            %     case 'Sobel'
            %         defaultT = 0.15;
            %         app.cconstantEditField.Enable = "on";
            %     case {'Prewitt', 'Roberts'}
            %         defaultT = 0.15;
            %     case 'Canny'
            %         defaultT = 0.10;
            %         app.sigmaEditField.Enable = "on";
            %         app.AutoThresholdCheckBox.Enable = "on";
            %     otherwise
            %         defaultT = app.ThresholdSlider.Value;
            % end
            % 
            % % Keep it in [0,1] just to be safe
            % defaultT = max(0, min(1, defaultT));
            % 
            % % Update both controls so they stay in sync
            % app.ThresholdSlider.Value      = defaultT;
            % app.ThresholdEditField.Value   = defaultT;
        end

        % Callback function
        function ThresholdSliderValueChanged(app, event)
            % value = app.ThresholdSlider.Value;
            % app.ThresholdEditField.Value = value;
        end

        % Callback function
        function ThresholdEditFieldValueChanged(app, event)
            % value = app.ThresholdEditField.Value;
            % 
            % % Clip 
            % value = max(0, min(1, value));
            % app.ThresholdEditField.Value = value;
            % app.ThresholdSlider.Value = value;
        end

        % Callback function
        function AreaOpenSliderValueChanged(app, event)
            % value = app.AreaOpenSlider.Value;
            % app.AreaOpenEditField.Value = value;
        end

        % Callback function
        function AreaOpenEditFieldValueChanged(app, event)
            % value = app.AreaOpenEditField.Value;
            % 
            % % Clip 
            % value = max(0, min(numel(app.GrayImage), value));
            % app.AreaOpenEditField.Value = value;
            % app.AreaOpenSlider.Value = value;
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            % start_path = 'C:';
            dialog_title = 'Select Input Train Folder';
            
            folder_path = uigetdir(dialog_title);
            
            if folder_path == 0
               % User cancel
               return;
            end

            % Change Lamp State
            app.StatusLamp.Color = 'yellow';
            app.StatusLabel.Text = 'Training in progress';

            % Load dataset
            imds = imageDatastore(folder_path, "IncludeSubfolders", true, 'LabelSource','foldernames');
            inputSize = [227 227 3];

            augimds = augmentedImageDatastore(inputSize(1:2), imds);

            layers = [
                imageInputLayer(inputSize)
                convolution2dLayer(11, 8, 'Padding','same')
                batchNormalizationLayer
                reluLayer
                maxPooling2dLayer(2, Stride=2)

                dropoutLayer(0.1)

                convolution2dLayer(5, 16, 'Padding','same')
                batchNormalizationLayer
                reluLayer
                maxPooling2dLayer(2, Stride=2)

                dropoutLayer(0.1)

                convolution2dLayer(3, 32, 'Padding','same')
                batchNormalizationLayer
                reluLayer

                dropoutLayer(0.1)

                convolution2dLayer(3, 32, 'Padding','same')
                batchNormalizationLayer
                reluLayer

                % convolution2dLayer(3, 16, 'Padding','same')
                % batchNormalizationLayer
                % reluLayer

                dropoutLayer(0.2)

                fullyConnectedLayer(5)
                dropoutLayer(0.5)

                fullyConnectedLayer(3)
                softmaxLayer('Name','fc_output')
                ];

            classNames = unique(imds.Labels, 'stable'); 
            customWeights = [2.0; 2.0; 2.0];

            % Convert the layer array to a layer graph
            lgraph = layerGraph(layers);
            
            % Create weighted classification layer
            weightedClassificationLayer = classificationLayer(...
                'Classes', classNames, ...
                'ClassWeights', customWeights, ...
                'Name', 'weighted_output');
            
            % Replace the final layer of the graph 
            lgraph = addLayers(lgraph, weightedClassificationLayer);
            
            % Connect the new layer to the fully connected layer
            lgraph = connectLayers(lgraph, 'fc_output', 'weighted_output');
            
            options = trainingOptions("adam", ...
                MaxEpochs=10, ...
                InitialLearnRate=1e-4, ...
                Plots="training-progress", ...
                Verbose=false);
            
            net = trainNetwork(augimds, lgraph, options);

            % Change Lamp State
            app.StatusLamp.Color = 'green';
            app.StatusLabel.Text = 'Model Trained';

            % Save model
            app.SavedModel = net;
            app.ClassNames = classNames;
            save('model.mat', 'net', 'classNames');
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [fname, fpath] = uigetfile({'*.mat;','Mat Files'}, ...
                                       'Select Input Model');
            if isequal(fname,0)
                return; % user cancelled
            end
            
            fullPath = fullfile(fpath, fname);
            load(fullPath, 'net', 'classNames');

            % Save Model
            app.SavedModel = net;
            app.ClassNames = classNames;

            % Change Lamp State
            app.StatusLamp.Color = 'green';
            app.StatusLabel.Text = 'Model Trained';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxesEdge = uiaxes(app.GridLayout);
            title(app.UIAxesEdge, 'Edge Image')
            xlabel(app.UIAxesEdge, 'X')
            ylabel(app.UIAxesEdge, 'Y')
            zlabel(app.UIAxesEdge, 'Z')
            app.UIAxesOriginal = uiaxes(app.GridLayout);
            title(app.UIAxesOriginal, 'Original Image')
            xlabel(app.UIAxesOriginal, 'X')
            ylabel(app.UIAxesOriginal, 'Y')
            zlabel(app.UIAxesOriginal, 'Z')
            app.UIAxesFill = uiaxes(app.GridLayout);
            title(app.UIAxesFill, 'Fill Image')
            xlabel(app.UIAxesFill, 'X')
            ylabel(app.UIAxesFill, 'Y')
            zlabel(app.UIAxesFill, 'Z')
            app.UIAxesSegmented = uiaxes(app.GridLayout);
            title(app.UIAxesSegmented, 'Segmented Image')
            xlabel(app.UIAxesSegmented, 'X')
            ylabel(app.UIAxesSegmented, 'Y')
            zlabel(app.UIAxesSegmented, 'Z')
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.GridLayout2 = uigridlayout(app.ControlPanel);
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ClassifyImageButton = uibutton(app.GridLayout2, 'push');
            app.ClassifyImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyImageButtonPushed, true);
            app.ClassifyImageButton.Text = 'Classify Image';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.TrainModelButton = uibutton(app.GridLayout2, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.StatusLamp = uilamp(app.GridLayout2);
            app.LoadModelButton = uibutton(app.GridLayout2, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'Load Model';
            app.StatusLabel = uilabel(app.GridLayout2);
            app.StatusLabel.Text = 'Untrained';
            app.ClassificationTitleLabel = uilabel(app.GridLayout);
            app.ClassificationTitleLabel.Text = 'Classification';
            app.ClassificationLabel = uilabel(app.GridLayout);
            app.ClassificationLabel.Text = '...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tugas4_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end